classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        PushTool                    matlab.ui.container.toolbar.PushTool
        Switch_2                    matlab.ui.control.Switch
        Switch                      matlab.ui.control.Switch
        TabGroup5                   matlab.ui.container.TabGroup
        AllFilesTab                 matlab.ui.container.Tab
        AllFilesListBox             matlab.ui.control.ListBox
        LastUsedTab                 matlab.ui.container.Tab
        LastUsedList                matlab.ui.control.ListBox
        TabGroup4                   matlab.ui.container.TabGroup
        PropiedadesTab              matlab.ui.container.Tab
        Seal2Panel                  matlab.ui.container.Panel
        TextArea_2                  matlab.ui.control.TextArea
        TrascribirButton_2          matlab.ui.control.Button
        Seal1Panel                  matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        TrascribirButton            matlab.ui.control.Button
        PianoTab                    matlab.ui.container.Tab
        TabGroup2                   matlab.ui.container.TabGroup
        GrabarTab                   matlab.ui.container.Tab
        OperarPanel                 matlab.ui.container.Panel
        GuardarButton               matlab.ui.control.Button
        InvertirButton              matlab.ui.control.Button
        LEditField                  matlab.ui.control.NumericEditField
        LEditFieldLabel             matlab.ui.control.Label
        MEditField                  matlab.ui.control.NumericEditField
        MEditFieldLabel             matlab.ui.control.Label
        InterpolarButton            matlab.ui.control.Button
        DiezmarButton               matlab.ui.control.Button
        Button_13                   matlab.ui.control.Button
        Button_12                   matlab.ui.control.Button
        GenerarPanel                matlab.ui.container.Panel
        FsHzEditField               matlab.ui.control.NumericEditField
        FsHzEditFieldLabel          matlab.ui.control.Label
        DuracionsEditField          matlab.ui.control.NumericEditField
        DuracionsEditFieldLabel     matlab.ui.control.Label
        AmplitudEditField           matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel      matlab.ui.control.Label
        FrecuenciaHzEditField       matlab.ui.control.NumericEditField
        FrecuenciaHzEditFieldLabel  matlab.ui.control.Label
        SealDropDown                matlab.ui.control.DropDown
        SealDropDownLabel           matlab.ui.control.Label
        GrabarPanel                 matlab.ui.container.Panel
        Button_11                   matlab.ui.control.Button
        Button_7                    matlab.ui.control.Button
        ReproducirPanel             matlab.ui.container.Panel
        Button_10                   matlab.ui.control.Button
        Button_9                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        FiltarTab                   matlab.ui.container.Tab
        RuidoTab                    matlab.ui.container.Tab
        TabGroup                    matlab.ui.container.TabGroup
        GraficasTab                 matlab.ui.container.Tab
        UIAxes_f2                   matlab.ui.control.UIAxes
        UIAxes_t2                   matlab.ui.control.UIAxes
        UIAxes_f1                   matlab.ui.control.UIAxes
        UIAxes_t1                   matlab.ui.control.UIAxes
        ChatBotTab                  matlab.ui.container.Tab
    end


    properties (Access = private)
        audioPlayer;
        isStopped = true;
        timer;
        recorder; %  Objeto para almacenar el grabador de audio
        fileNames = {}; % Celda para almacenar los nombres de los archivos grabados
        frecuencia = 1000;      % Valor por inicial
        amplitud = 1;
        duracion = 1;
        fs = 44100;
        L = 1;
        M = 1;
        sig1;
        sig1_fs;
        sig1_t_max;
        sig1_name;              %Señal 1
        sig2;
        sig2_fs;
        sig2_t_max;
        sig2_name;              %Señal 2
    end
    methods (Access = public)

        % Startup function
        function StartupFcn(app)
            % Definir el directorio de los archivos .wav
            directoryPath = 'audios/';  % Ruta al directorio de los archivos de audio
            % Obtener todos los archivos .wav del directorio especificado
            wavFiles = dir(fullfile(directoryPath, '*.wav'));
            % Crear una lista de las rutas completas de los archivos .wav
            wavFileNames = {wavFiles.name};
            % Actualizar la lista con los archivos del directorio actual
            app.AllFilesListBox.Items = wavFileNames; % Mostrar las rutas completas de archivos .wav en la lista
        end
        function [grafica_t, grafica_f] = grafica_vacia(app)
            opcion = app.Switch.Value;
            disp(opcion);
            switch opcion
                case 'Grafica 1'
                    grafica_t = app.UIAxes_t1;
                    grafica_f = app.UIAxes_f1;
                case 'Grafica 2'
                    grafica_t = app.UIAxes_t2;
                    grafica_f = app.UIAxes_f2;
                otherwise
                    grafica_t = app.UIAxes_t1;
                    grafica_f = app.UIAxes_f1;
            end
        end
        % Función para actualizar el progreso en la gráfica
        function updateProgress(app, reset)
            [grafica_t, ~] = grafica_vacia(app);
            if nargin < 2
                reset = false;
            end
            if reset
                % Reiniciar la gráfica
                opcion = app.Switch.Value;
                if strcmp(opcion, 'Grafica 1')
                    plot(grafica_t, linspace(0,app.sig1_t_max,length(app.sig1)), app.sig1);
                elseif strcmp(opcion, 'Grafica 2')
                    plot(grafica_t, linspace(0,app.sig2_t_max,length(app.sig2)), app.sig2);
                end
                hold(grafica_t, 'on');
                grafica_t.Children(end).Color = 'r'; % Línea de progreso roja
                grafica_t.Children(end).XData = [0, 0];
                hold(grafica_t, 'off');
            else
                % Actualizar la posición de la línea de progreso
                currentTime = app.audioPlayer.CurrentSample / app.Fs;
                app.UIAxes.Children(end).XData = [currentTime, currentTime];
            end
        end
        function plotSignal(app)
            [grafica_t, grafica_f] = grafica_vacia(app);
            if strcmp(app.Switch.Value, 'Grafica 1')
                signal = app.sig1;
                fs = app.sig1_fs;
                t_max = app.sig1_t_max;
            elseif strcmp(app.Switch.Value, 'Grafica 2')
                signal = app.sig2;
                fs = app.sig2_fs;
                t_max = app.sig2_t_max;
            else
                % Si el Switch tiene un valor inesperado
                app.TextArea.Value = 'Error: Selección de gráfica inválida.';
                return;
            end

            % Verificar que la señal no esté vacía
            if isempty(signal)
                app.TextArea.Value = 'Error: La señal seleccionada está vacía.';
                return;
            end

            cla(grafica_f);
            cla(grafica_t);

            % Mostrar la señal en el tiempo en grafica_t
            t = linspace(0, t_max, length(signal)); % Crear el vector de tiempo
            plot(grafica_t, t, signal, 'LineWidth', 1.5);
            title(grafica_t, 'Señal en el Tiempo');
            xlabel(grafica_t, 'Tiempo (s)');
            ylabel(grafica_t, 'Amplitud');
            % Determinar el tipo de análisis (Espectrograma o FFT)
            if strcmp(app.Switch_2.Value, 'Espectrograma')
                % Generar el espectrograma
                [s, f, t, p] = spectrogram(signal, hamming(256), 128, 256, fs, 'yaxis');
                surf(grafica_f, t, f, 10*log10(p), 'EdgeColor', 'none'); % Usar surf para graficar
                axis(grafica_f, 'tight');
                view(grafica_f, 2); % Vista 2D
                colorbar(grafica_f, 'eastoutside'); % Agregar barra de color
                title(grafica_f, 'Espectrograma');
                xlabel(grafica_f, 'Tiempo (s)');
                ylabel(grafica_f, 'Frecuencia (Hz)');
                zlabel(grafica_f, 'Potencia (dB)');
            elseif strcmp(app.Switch_2.Value, 'Espectro')
                % Generar la FFT
                N = length(signal); % Número de muestras
                f = linspace(0, fs/2, floor(N/2) + 1); % Vector de frecuencias
                fft_signal = fft(signal); % Transformada de Fourier
                magnitude = abs(fft_signal(1:floor(N/2) + 1)); % Magnitud positiva
                plot(grafica_f, f, magnitude, 'LineWidth', 1.5); % Graficar en grafica_f
                title(grafica_f, 'Espectro de Frecuencia');
                xlabel(grafica_f, 'Frecuencia (Hz)');
                ylabel(grafica_f, 'Magnitud');
            else
                % Si el Switch_2 tiene un valor inesperado
                app.TextArea.Value = 'Error: Selección de análisis inválida.';
                return;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartupFcn();
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            fs = 44100; % Frecuencia de muestreo
            nBits = 16; % Número de bits por muestra
            nChannels = 1; % Número de canales (1 para mono)

            % Crear el objeto audiorecorder
            app.recorder = audiorecorder(fs, nBits, nChannels);

            % Iniciar la grabación
            record(app.recorder);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            % Detener la grabación
            stop(app.recorder);
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = getaudiodata(app.recorder);
                app.sig1_fs = app.recorder.SampleRate;
                app.sig1_t_max = (length(app.sig1) - 1)/app.sig1_fs;
                timestamp = datetime('now', 'Format', 'HH_mm_ddMMyyyy');
                app.sig1_name = ['audios/grabacion_' char(timestamp)];
                filename = [app.sig1_name, '.wav'];
                audiowrite(filename, app.sig1, app.sig1_fs);
                plotSignal();
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = getaudiodata(app.recorder);
                app.sig2_fs = app.recorder.SampleRate;
                app.sig2_t_max = (length(app.sig2) - 1)/app.sig2_fs;
                timestamp = datetime('now', 'Format', 'HH_mm_ddMMyyyy');
                app.sig2_name = ['audios/grabacion_' char(timestamp) ];
                filename = [app.sig2_name, '.wav'];
                audiowrite(filename, app.sig2, app.sig2_fs);
                plotSignal(app);
            end

            % Actualizar la lista de nombres de archivos y la lista desplegable
            app.fileNames{end+1} = filename; % Añadir el nuevo nombre a la celda
            app.LastUsedList.Items = app.fileNames; % Actualizar la lista con los nombres

            %Actualizar la lista de ficheros .wav en el directorio actual
            app.StartupFcn();
        end

        % Double-clicked callback: LastUsedList
        function LastUsedListDoubleClicked(app, event)
            selectedFile = app.AllFilesListBox.Value;
            directoryPath = 'audios/';

            if ~isempty(selectedFile)
                % Construir la ruta completa al archivo .wav en la carpeta audios
                fullPath = fullfile(directoryPath, selectedFile);

                % Leer el archivo de audio desde la ruta completa
                [audioData, fs] = audioread(fullPath);
                opcion = app.Switch.Value;
                if strcmp(opcion, 'Grafica 1')
                    app.sig1 = audioData;
                    app.sig1_fs = fs;
                    app.sig1_t_max = (length(audioData) - 1) / app.sig1_fs;
                    app.sig1_name = erase(selectedFile, '.wav');
                elseif strcmp(opcion, 'Grafica 2')
                    app.sig2 = audioData;
                    app.sig2_fs = fs;
                    app.sig2_t_max = (length(audioData) - 1) / app.sig2_fs;
                    app.sig2_name = erase(selectedFile, '.wav');
                end
                    plotSignal(app);
            end

        end

        % Double-clicked callback: AllFilesListBox
        function AllFilesListBoxDoubleClicked(app, event)
            selectedFile = app.AllFilesListBox.Value;
            directoryPath = 'audios/';

            if ~isempty(selectedFile)
                % Construir la ruta completa al archivo .wav en la carpeta audios
                fullPath = fullfile(directoryPath, selectedFile);

                % Leer el archivo de audio desde la ruta completa
                [audioData, fs] = audioread(fullPath);
                opcion = app.Switch.Value;
                if strcmp(opcion, 'Grafica 1')
                    app.sig1 = audioData;
                    app.sig1_fs = fs;
                    app.sig1_t_max = (length(audioData) - 1) / app.sig1_fs;
                    app.sig1_name = erase(selectedFile, '.wav');
                elseif strcmp(opcion, 'Grafica 2')
                    app.sig2 = audioData;
                    app.sig2_fs = fs;
                    app.sig2_t_max = (length(audioData) - 1) / app.sig2_fs;
                    app.sig2_name = erase(selectedFile, '.wav');
                end
                    plotSignal(app);
            end


        end

        % Clicked callback: SealDropDown
        function SealDropDownClicked(app, event)
            % Almacena amplitud y frecuencia en variables locales
            [grafica_t, grafica_f] = grafica_vacia(app);
            A = app.amplitud;
            f = app.frecuencia;
            Fs = app.fs;
            t_max = app.duracion;
            y = [];
            name = '';

            % Muestra los valores de frecuencia y amplitud para verificación
            disp(['Frecuencia: ', num2str(f)]);
            disp(['Amplitud: ', num2str(A)]);
             
            cla(grafica_t);                % Limpia el gráfico temporal
            cla(grafica_f);                % Limpia el gráfico de frecuencia

           
            % Verificación de frecuencia cero
            if f == 0 || Fs == 0

                % Define el tiempo y la señal como una línea constante
                t = 0:0.01:t_max;
                y = A * ones(size(t));
                name = ['sig_f0_A',A];
            end

            % Obtiene el ítem seleccionado
            item = event.InteractionInformation.Item;
            item = string(item);

            % Ajuste del eje temporal según la frecuencia de la señal

            Ts = 1 / Fs;                     % Periodo de la señal
            t = 0:Ts:t_max;                % Cubre algunos ciclos para visualización adecuada
            switch item
                case "1"  % Señal Senoidal
                    name = ['seno_f',num2str(f),'_A',num2str(A)];
                    y = A * sin(2 * pi * f * t);

                case "2"  % Señal Cosenoidal
                    name = ['cos_f',num2str(f),'_A',num2str(A)];
                    y = A * cos(2 * pi * f * t);
                    
                case "3"  % Onda Cuadrada
                    name = ['square_f',num2str(f),'_A',num2str(A)];
                    y = A * square(2 * pi * f * t);

                case "4"  % Onda Triangular
                    name = ['triangular_f',num2str(f),'_A',num2str(A)];
                    y = A * sawtooth(2 * pi * f * t, 0.5);
                   
                case "5"  % Onda Diente de Sierra
                    name = ['sawtooth_f',num2str(f),'_A',num2str(A)];
                    y = A * sawtooth(2 * pi * f * t);
                    
            end
                opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = y;
                app.sig1_fs = Fs;
                app.sig1_t_max = t_max;
                app.sig1_name = name; 
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = y;
                app.sig2_fs = Fs;
                app.sig2_t_max = t_max;
                app.sig2_name = name; 
            end
                plotSignal(app);
        end

        % Value changed function: FrecuenciaHzEditField
        function FrecuenciaHzEditFieldValueChanged(app, event)
             app.frecuencia = app.FrecuenciaHzEditField.Value;  % Guarda el valor en la propiedad
            disp(['Nueva frecuencia: ', num2str(app.frecuencia)]);  % Verifica el valor
        end

        % Value changed function: AmplitudEditField
        function AmplitudEditFieldValueChanged(app, event)
            app.amplitud = app.AmplitudEditField.Value;  % Guarda el valor en la propiedad
            disp(['Nueva amplitud: ', num2str(app.amplitud)]);  % Verifica el valor
        end

        % Value changed function: DuracionsEditField
        function DuracionsEditFieldValueChanged(app, event)
            app.duracion = app.DuracionsEditField.Value;
            disp(['Nueva Duracion: ', num2str(app.duracion)]);  % Verifica el valor
        end

        % Value changed function: FsHzEditField
        function FsHzEditFieldValueChanged(app, event)
            app.fs = app.FsHzEditField.Value;
            disp(['Nueva fs: ', num2str(app.frecuencia)]);  % Verifica el valor
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if isempty(app.sig1) || isempty(app.sig2)
                error('Las señales no pueden estar vacías.');
            end
            if iscolumn(app.sig1)
                app.sig1 = app.sig1';   % Convertir a vector fila si es necesario
            end
            if iscolumn(app.sig2)
                app.sig2 = app.sig2';   % Convertir a vector fila si es necesario
            end
            % Remuestrear la segunda señal para coincidir con la frecuencia de la primera señal
            sig2_remuestreada = resample(app.sig2, app.sig1_fs, app.sig2_fs);
            % Ajustar la duración de ambas señales (relleno con ceros si es necesario)
            if length(app.sig1) < length(sig2_remuestreada)
                app.sig1 = [app.sig1, zeros(1, length(sig2_remuestreada) - length(app.sig1))];
            elseif length(sig2_remuestreada) < length(app.sig1)
                sig2_remuestreada = [sig2_remuestreada, zeros(1, length(app.sig1) - length(sig2_remuestreada))];
            end
            % Sumar las señales
            sum_signal = app.sig1 + sig2_remuestreada;

            % Almacenar la señal sumada en la variable de salida y los parámetros
            y = sum_signal;
            fs = app.sig1_fs;
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = y;
                app.sig1_fs = fs;
                app.sig1_t_max = (length(sum_signal) - 1) / fs;
                app.sig1_name = [app.sig1_name,'_plus_',app.sig2_name]; 
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = y;
                app.sig2_fs = fs;
                app.sig2_t_max = (length(sum_signal) - 1) / fs;
                app.sig2_name = [app.sig2_name,'_plus_',app.sig1_name];
            end
                plotSignal(app);
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            % Verificar que las señales no estén vacías
            if isempty(app.sig1) || isempty(app.sig2)
                error('Las señales no pueden estar vacías.');
            end
             if iscolumn(app.sig1)
                app.sig1 = app.sig1';   % Convertir a vector fila si es necesario
            end
            if iscolumn(app.sig2)
                app.sig2 = app.sig2';   % Convertir a vector fila si es necesario
            end
            % Remuestrear la segunda señal para que coincida con la frecuencia de la primera señal
            sig2_remuestreada = resample(app.sig2, app.sig1_fs, app.sig2_fs);
            % Ajustar la duración de ambas señales (relleno con ceros si es necesario)
            if length(app.sig1) < length(sig2_remuestreada)
                app.sig1 = [app.sig1, zeros(1, length(sig2_remuestreada) - length(app.sig1))];
            elseif length(sig2_remuestreada) < length(app.sig1)
                sig2_remuestreada = [sig2_remuestreada, zeros(1, length(app.sig1) - length(sig2_remuestreada))];
            end
            % Multiplicar las señales
            mult_signal = app.sig1 .* sig2_remuestreada;
            % Almacenar la señal multiplicada en la variable de salida y los parámetros
            y = mult_signal;
            fs = app.sig1_fs;
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = y;
                app.sig1_fs = fs;
                app.sig1_t_max = (length(mult_signal) - 1) / fs;
                app.sig1_name = [app.sig1_name,'_times_',app.sig2_name];
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = y;
                app.sig2_fs = fs;
                app.sig2_t_max = (length(mult_signal) - 1) / fs;
                app.sig2_name = [app.sig2_name,'_times_',app.sig1_name];
            end
                plotSignal(app);
        end

        % Button pushed function: DiezmarButton
        function DiezmarButtonPushed(app, event)
            M = app.M;
            if M <= 0
                error('El factor de diezmo M debe ser mayor a 0');
            end
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = downsample(app.sig1, M);
                app.sig1_fs = app.sig1_fs / M;
                app.sig1_name = [app.sig1_name,'_downsample_M',num2str(M)];
                
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = downsample(app.sig2, M);
                app.sig2_fs = app.sig2_fs / M;
                app.sig2_name = [app.sig2_name,'_downsample_M',num2str(M)];
            end
                plotSignal(app);
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            app.M = app.MEditField.Value;
            
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            app.L = app.LEditField.Value;
            
        end

        % Button pushed function: InterpolarButton
        function InterpolarButtonPushed(app, event)
            L = app.L;
            if L <= 0
                error('El factor de interpolacion L debe ser mayor a 0');
            end
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = interp(app.sig1, L);
                app.sig1_fs = app.sig1_fs * L;
                app.sig1_name = [app.sig1_name,'_interpolate_L',num2str(L)];
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = interp(app.sig2, L);
                app.sig2_fs = app.sig2_fs * L;
                app.sig2_name = [app.sig2_name,'_interpolate_L',num2str(L)];
            end
                plotSignal(app);
        end

        % Button pushed function: InvertirButton
        function InvertirButtonPushed(app, event)
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = flip(app.sig1);  % Invertir la señal en lugar de diezmar
                app.sig1_name = [app.sig1_name,'_fliped'];
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = flip(app.sig2);  % Invertir la señal en lugar de diezmar
                app.sig2_name = [app.sig2_name,'_fliped'];
            end
            plotSignal(app);
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                % Guardar el archivo de audio como "grabacion.wav"
                if max(abs(app.sig1)) >= 1
                    app.sig1 = app.sig1 / max(abs(app.sig1));
                end
                sig1_remuestreada = resample(app.sig1, 44100, app.sig1_fs);
                filename = ['audios/',app.sig1_name,'.wav'];
                % Guardar el archivo de audio
                audiowrite(filename, sig1_remuestreada, 44100);
            elseif strcmp(opcion, 'Grafica 2')
                % Guardar el archivo de audio como "grabacion.wav"
                if max(abs(app.sig2)) >= 1
                    app.sig2 = app.sig2 / max(abs(app.sig2));
                end
                sig2_remuestreada = resample(app.sig2, 44100, app.sig2_fs);
                filename = ['audios/',app.sig2_name,'.wav'];
                % Guardar el archivo de audio
                audiowrite(filename, sig2_remuestreada, 44100);
            end
            % Actualizar la lista de nombres de archivos y la lista desplegable
            app.fileNames{end+1} = filename; % Añadir el nuevo nombre a la celda
            app.LastUsedList.Items = app.fileNames; % Actualizar la lista con los nombres

            %Actualizar la lista de ficheros .wav en el directorio actual
            app.StartupFcn();
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.isStopped
                opcion = app.Switch.Value; 
                if strcmp(opcion, 'Grafica 1')
                    app.audioPlayer = audioplayer(app.sig1, app.sig1_fs);
                elseif strcmp(opcion, 'Grafica 2')
                    app.audioPlayer = audioplayer(app.sig2, app.sig2_fs);
                end
                app.timer = timer('ExecutionMode', 'fixedRate', ...
                    'Period', 0.1, ...
                    'TimerFcn', @(~,~) app.updateProgress());
                play(app.audioPlayer);
                start(app.timer);
                app.isStopped = false;
            else
                resume(app.audioPlayer);
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if isplaying(app.audioPlayer)
                pause(app.audioPlayer);
                stop(app.timer);
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            stop(app.audioPlayer);
            stop(app.timer);
            app.isStopped = true; % Marcar como detenido para reiniciar la próxima veZ
        end

        % Button pushed function: TrascribirButton
        function TrascribirButtonPushed(app, event)
            % Guardar el archivo de audio como "grabacion.wav"
            if max(abs(app.sig1)) >= 1
                app.sig1 = app.sig1 / max(abs(app.sig1));
            end
            sig1_remuestreada = resample(app.sig1, 44100, app.sig1_fs);
            filename = ['audios/',app.sig1_name,'.wav'];
            % Guardar el archivo de audio
            audiowrite(filename, sig1_remuestreada, 44100);
            file_path = ['./',filename]
            % URL del servidor Python
            serverUrl = 'http://127.0.0.1:5000/transcribe';

            % Crear el JSON con el nombre del archivo
            data = struct('filename', file_path);

            options = weboptions('RequestMethod', 'post', 'MediaType', 'application/json', 'Timeout', 30); % Set timeout to 30 seconds
            response = webwrite(serverUrl, data, options);

            % Procesar la respuesta
            if isfield(response, 'text')
                app.TextArea.Value = response.text;
            else
                app.TextArea.Value = response.error;
            end

        end

        % Button pushed function: TrascribirButton_2
        function TrascribirButton_2Pushed(app, event)
            % Guardar el archivo de audio como "grabacion.wav"
            if max(abs(app.sig2)) >= 1
                app.sig2 = app.sig2 / max(abs(app.sig2));
            end
            sig2_remuestreada = resample(app.sig2, 44100, app.sig2_fs);
            filename = ['audios/',app.sig2_name,'.wav'];
            % Guardar el archivo de audio
            audiowrite(filename, sig2_remuestreada, 44100);
            file_path = ['./',filename]
            % URL del servidor Python
            serverUrl = 'http://127.0.0.1:5000/transcribe';

            % Crear el JSON con el nombre del archivo
            data = struct('filename', file_path);

            options = weboptions('RequestMethod', 'post', 'MediaType', 'application/json', 'Timeout', 30); % Set timeout to 30 seconds
            response = webwrite(serverUrl, data, options);

            % Procesar la respuesta
            if isfield(response, 'text')
                app.TextArea_2.Value = response.text;
            else
                app.TextArea_2.Value = response.error;
            end

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            % Determinar la gráfica a usar (Grafica 1 o Grafica 2)
            [~, grafica_f] = grafica_vacia(app);
            if strcmp(app.Switch.Value, 'Grafica 1')
                signal = app.sig1;
                fs = app.sig1_fs;
                t_max = app.sig1_t_max;
            elseif strcmp(app.Switch.Value, 'Grafica 2')
                signal = app.sig2;
                fs = app.sig2_fs;
                t_max = app.sig2_t_max;
            else
                % Si el Switch tiene un valor inesperado
                app.TextArea.Value = 'Error: Selección de gráfica inválida.';
                return;
            end

            cla(grafica_f);
            % Determinar el tipo de análisis (Espectrograma o FFT)
            if strcmp(app.Switch_2.Value, 'Espectrograma')
                % Generar el espectrograma
                [s, f, t, p] = spectrogram(signal, hamming(256), 128, 256, fs, 'yaxis');
                surf(grafica_f, t, f, 10*log10(p), 'EdgeColor', 'none'); % Usar surf para graficar
                axis(grafica_f, 'tight');
                view(grafica_f, 2); % Vista 2D
                colorbar(grafica_f, 'eastoutside'); % Agregar barra de color
                title(grafica_f, 'Espectrograma');
                xlabel(grafica_f, 'Tiempo (s)');
                ylabel(grafica_f, 'Frecuencia (Hz)');
                zlabel(grafica_f, 'Potencia (dB)');
            elseif strcmp(app.Switch_2.Value, 'Espectro')
                % Generar la FFT
                N = length(signal); % Número de muestras
                f = linspace(0, fs/2, floor(N/2) + 1); % Vector de frecuencias
                fft_signal = fft(signal); % Transformada de Fourier
                magnitude = abs(fft_signal(1:floor(N/2) + 1)); % Magnitud positiva

                plot(grafica_f, f, magnitude, 'LineWidth', 1.5); % Graficar en grafica_f
                title(grafica_f, 'Espectro de Frecuencia');
                xlabel(grafica_f, 'Frecuencia (Hz)');
                ylabel(grafica_f, 'Magnitud');
                grid(grafica_f, 'on');
            else
                % Si el Switch_2 tiene un valor inesperado
                app.TextArea.Value = 'Error: Selección de análisis inválida.';
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraficasTab = uitab(app.TabGroup);
            app.GraficasTab.Title = 'Graficas';
            app.UIAxes_t1 = uiaxes(app.GraficasTab);
            title(app.UIAxes_t1, 'Title')
            xlabel(app.UIAxes_t1, 'X')
            ylabel(app.UIAxes_t1, 'Y')
            zlabel(app.UIAxes_t1, 'Z')
            app.UIAxes_f1 = uiaxes(app.GraficasTab);
            title(app.UIAxes_f1, 'Title')
            xlabel(app.UIAxes_f1, 'X')
            ylabel(app.UIAxes_f1, 'Y')
            zlabel(app.UIAxes_f1, 'Z')
            app.UIAxes_t2 = uiaxes(app.GraficasTab);
            title(app.UIAxes_t2, 'Title')
            xlabel(app.UIAxes_t2, 'X')
            ylabel(app.UIAxes_t2, 'Y')
            zlabel(app.UIAxes_t2, 'Z')
            app.UIAxes_f2 = uiaxes(app.GraficasTab);
            title(app.UIAxes_f2, 'Title')
            xlabel(app.UIAxes_f2, 'X')
            ylabel(app.UIAxes_f2, 'Y')
            zlabel(app.UIAxes_f2, 'Z')
            app.ChatBotTab = uitab(app.TabGroup);
            app.ChatBotTab.Title = 'ChatBot';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.GrabarTab = uitab(app.TabGroup2);
            app.GrabarTab.Title = 'Grabar';
            app.ReproducirPanel = uipanel(app.GrabarTab);
            app.ReproducirPanel.Title = 'Reproducir';
            app.Button_2 = uibutton(app.ReproducirPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'img', 'b_reproducir.png');
            app.Button_2.Text = '';
            app.Button_9 = uibutton(app.ReproducirPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = fullfile(pathToMLAPP, 'img', 'b_detener.png');
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.ReproducirPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = fullfile(pathToMLAPP, 'img', 'b_pausar.png');
            app.Button_10.Text = '';
            app.GrabarPanel = uipanel(app.GrabarTab);
            app.GrabarPanel.Title = 'Grabar';
            app.Button_7 = uibutton(app.GrabarPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = fullfile(pathToMLAPP, 'img', 'b_grabar.png');
            app.Button_7.Text = '';
            app.Button_11 = uibutton(app.GrabarPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Icon = fullfile(pathToMLAPP, 'img', 'b_detener.png');
            app.Button_11.Text = '';
            app.GenerarPanel = uipanel(app.GrabarTab);
            app.GenerarPanel.Title = 'Generar';
            app.SealDropDownLabel = uilabel(app.GenerarPanel);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.GenerarPanel);
            app.SealDropDown.Items = {'Seno', 'Coseno', 'Onda Cuadrada', 'Onda Triangular', 'Diente de Sierra'};
            app.SealDropDown.ClickedFcn = createCallbackFcn(app, @SealDropDownClicked, true);
            app.SealDropDown.Value = 'Seno';
            app.FrecuenciaHzEditFieldLabel = uilabel(app.GenerarPanel);
            app.FrecuenciaHzEditFieldLabel.Text = 'Frecuencia (Hz)';
            app.FrecuenciaHzEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.FrecuenciaHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciaHzEditFieldValueChanged, true);
            app.FrecuenciaHzEditField.Value = 1000;
            app.AmplitudEditFieldLabel = uilabel(app.GenerarPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.AmplitudEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudEditFieldValueChanged, true);
            app.AmplitudEditField.Value = 1;
            app.DuracionsEditFieldLabel = uilabel(app.GenerarPanel);
            app.DuracionsEditFieldLabel.Text = 'Duracion (s)';
            app.DuracionsEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.DuracionsEditField.ValueChangedFcn = createCallbackFcn(app, @DuracionsEditFieldValueChanged, true);
            app.DuracionsEditField.Value = 1;
            app.FsHzEditFieldLabel = uilabel(app.GenerarPanel);
            app.FsHzEditFieldLabel.Text = 'Fs (Hz)';
            app.FsHzEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.FsHzEditField.ValueChangedFcn = createCallbackFcn(app, @FsHzEditFieldValueChanged, true);
            app.FsHzEditField.Value = 44100;
            app.OperarPanel = uipanel(app.GrabarTab);
            app.OperarPanel.Title = 'Operar';
            app.Button_12 = uibutton(app.OperarPanel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Icon = fullfile(pathToMLAPP, 'img', 'b_Suma.png');
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.OperarPanel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Icon = fullfile(pathToMLAPP, 'img', 'b_por.png');
            app.Button_13.Text = '';
            app.DiezmarButton = uibutton(app.OperarPanel, 'push');
            app.DiezmarButton.ButtonPushedFcn = createCallbackFcn(app, @DiezmarButtonPushed, true);
            app.DiezmarButton.Text = 'Diezmar';
            app.InterpolarButton = uibutton(app.OperarPanel, 'push');
            app.InterpolarButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolarButtonPushed, true);
            app.InterpolarButton.Text = 'Interpolar';
            app.MEditFieldLabel = uilabel(app.OperarPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.OperarPanel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 1;
            app.LEditFieldLabel = uilabel(app.OperarPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.OperarPanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Value = 1;
            app.InvertirButton = uibutton(app.OperarPanel, 'push');
            app.InvertirButton.ButtonPushedFcn = createCallbackFcn(app, @InvertirButtonPushed, true);
            app.InvertirButton.Text = 'Invertir';
            app.GuardarButton = uibutton(app.OperarPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.FiltarTab = uitab(app.TabGroup2);
            app.FiltarTab.Title = 'Filtar';
            app.RuidoTab = uitab(app.TabGroup2);
            app.RuidoTab.Title = 'Ruido';
            app.TabGroup4 = uitabgroup(app.UIFigure);
            app.PropiedadesTab = uitab(app.TabGroup4);
            app.PropiedadesTab.Title = 'Propiedades';
            app.Seal1Panel = uipanel(app.PropiedadesTab);
            app.Seal1Panel.Title = 'Señal 1';
            app.TrascribirButton = uibutton(app.Seal1Panel, 'push');
            app.TrascribirButton.ButtonPushedFcn = createCallbackFcn(app, @TrascribirButtonPushed, true);
            app.TrascribirButton.Text = 'Trascribir';
            app.TextArea = uitextarea(app.Seal1Panel);
            app.Seal2Panel = uipanel(app.PropiedadesTab);
            app.Seal2Panel.Title = 'Señal 2';
            app.TrascribirButton_2 = uibutton(app.Seal2Panel, 'push');
            app.TrascribirButton_2.ButtonPushedFcn = createCallbackFcn(app, @TrascribirButton_2Pushed, true);
            app.TrascribirButton_2.Text = 'Trascribir';
            app.TextArea_2 = uitextarea(app.Seal2Panel);
            app.PianoTab = uitab(app.TabGroup4);
            app.PianoTab.Title = 'Piano';
            app.TabGroup5 = uitabgroup(app.UIFigure);
            app.AllFilesTab = uitab(app.TabGroup5);
            app.AllFilesTab.Title = 'All Files';
            app.AllFilesListBox = uilistbox(app.AllFilesTab);
            app.AllFilesListBox.Items = {};
            app.AllFilesListBox.DoubleClickedFcn = createCallbackFcn(app, @AllFilesListBoxDoubleClicked, true);
            app.AllFilesListBox.Value = {};
            app.LastUsedTab = uitab(app.TabGroup5);
            app.LastUsedTab.Title = 'Last Used';
            app.LastUsedList = uilistbox(app.LastUsedTab);
            app.LastUsedList.Items = {};
            app.LastUsedList.DoubleClickedFcn = createCallbackFcn(app, @LastUsedListDoubleClicked, true);
            app.LastUsedList.Value = {};
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Grafica 1', 'Grafica 2'};
            app.Switch.Value = 'Grafica 1';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Espectro', 'Espectrograma'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Espectro';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end